classdef yFoil < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        yFoil10UIFigure              matlab.ui.Figure
        GridLayout5                  matlab.ui.container.GridLayout
        YFoilProfileOptionsPanel_2   matlab.ui.container.Panel
        GridLayout6                  matlab.ui.container.GridLayout
        YFoilElapsedLabel            matlab.ui.control.Label
        YFoilLowerOutputLabel        matlab.ui.control.Label
        YFoilUpperOutputLabel        matlab.ui.control.Label
        LowerSurfaceQualitySpinner   matlab.ui.control.Spinner
        LowerSurfaceQualitySpinnerLabel  matlab.ui.control.Label
        ChordParameterNameEditField  matlab.ui.control.EditField
        ChordParameterNameEditFieldLabel  matlab.ui.control.Label
        UpperSurfaceQualitySpinner   matlab.ui.control.Spinner
        UpperSurfaceQualitySpinnerLabel  matlab.ui.control.Label
        Hyperlink                    matlab.ui.control.Hyperlink
        Image                        matlab.ui.control.Image
        ReferenceProfilePanel        matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        NacaElapsedLabel             matlab.ui.control.Label
        NacaQualitySpinner           matlab.ui.control.Spinner
        QualitySpinnerLabel          matlab.ui.control.Label
        Lamp                         matlab.ui.control.Lamp
        QueryField                   matlab.ui.control.EditField
        NACAEditField_2Label         matlab.ui.control.Label
        EquationsPanel               matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        ecly                         matlab.ui.control.Button
        elly                         matlab.ui.control.Label
        eclx                         matlab.ui.control.Button
        ellx                         matlab.ui.control.Label
        ecuy                         matlab.ui.control.Button
        eluy                         matlab.ui.control.Label
        ecux                         matlab.ui.control.Button
        elux                         matlab.ui.control.Label
        Axes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        naca % Current NacaProfile
        yf {mustBeA(yf, "YProfile")} = YProfile.empty() % Current YProfile
    end
    
    methods (Access = private)
        % Dependency chain
        
        function updateNaca(app)
            % Regenerate the Naca profile and update dependents
            app.naca = NacaProfile.GenerateFromDigits(app.QueryField.Value, app.getQuality());
            app.updateYf();
        end
        
        function updateYf(app)
            % Regenerate the yFoil profile and update dependents 
            tic();
            qual = [app.UpperSurfaceQualitySpinner.Value, app.LowerSurfaceQualitySpinner.Value];
            app.yf = YProfile(app.naca, qual);
            app.YFoilElapsedLabel.Text = sprintf("%.3fms",toc() * 1000);
            app.updateEquationLabels();   
            app.updateAxes();
        end

        function updateAxes(app)
            % Clear previous content in the axes, if needed
            cla(app.Axes);
            % Set x-axes limit
            xlim(app.Axes,[-0.2 1.2]);
            % Set y-axis limit
            ylim(app.Axes,[-ceil((app.naca.T / 2 + 0.05) * 10) / 10, ceil((app.naca.M + app.naca.T / 2 + 0.05) * 10) / 10]);

            hold(app.Axes,"on");

            % Plot the upper surface, chamber line and lower surface of the NACA profile
            % on the axes
            plot(app.Axes,app.naca.UpperSurface(1,:), app.naca.UpperSurface(2,:), 'mx');
            plot(app.Axes,app.naca.ChamberLine(1,:), app.naca.ChamberLine(2,:), Color='y');
            plot(app.Axes,app.naca.LowerSurface(1,:), app.naca.LowerSurface(2,:), 'mx');
            
            % Plot the yFoil
            plot(app.Axes,app.yf.UpperSurface(1,:), app.yf.UpperSurface(2,:), Color='blue');
            plot(app.Axes,app.yf.LowerSurface(1,:), app.yf.LowerSurface(2,:), Color='blue');

            hold(app.Axes,"off");
        end
        
        function updateEquationLabels(app)
            app.YFoilUpperOutputLabel.Text = ...
                sprintf("n = %d, r² = %g", ...
                length(app.yf.UpperCoefficients), app.yf.UpperR2);
            app.YFoilLowerOutputLabel.Text = ...
                sprintf("n = %d, r² = %g", ...
                length(app.yf.LowerCoefficients), app.yf.LowerR2);
            app.elux.Text = app.yf.GetEquation('ux', app.ChordParameterNameEditField.Value);
            app.eluy.Text = app.yf.GetEquation('uy', app.ChordParameterNameEditField.Value);
            app.ellx.Text = app.yf.GetEquation('lx', app.ChordParameterNameEditField.Value);
            app.elly.Text = app.yf.GetEquation('ly', app.ChordParameterNameEditField.Value);
        end
        
        function xPositionvector = getQuality(app)
            xPositionvector = (0:0.4/2^(app.NacaQualitySpinner.Value):1).^3;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Ensure that the plot scale is square (so that there's no distortion)
            daspect(app.Axes, [1 1 1]);

            app.updateNaca();
        end

        % Button pushed function: eclx, ecly, ecux, ecuy
        function copyButtonPushed(app, event)
            clipboard("copy", app.yf.GetEquation(char(event.Source.Tag), app.ChordParameterNameEditField.Value));            
        end

        % Value changing function: QueryField
        function QueryFieldValueChanging(app, event)
            app.Lamp.Color = [0.5,0.5,0.5];
        end

        % Value changed function: QueryField
        function QueryFieldValueChanged(app, event)
            tic();
            newNaca = NacaProfile.GenerateFromDigits(app.QueryField.Value, ...
                app.getQuality());
            app.NacaElapsedLabel.Text = sprintf("%.3fms",toc() * 1000);
            if (~isempty(newNaca))
                app.Lamp.Color = [0,1,0];
                app.naca = newNaca;
                app.updateYf();
            else
                app.Lamp.Color = [1,0,0];
            end
        end

        % Value changed function: ChordParameterNameEditField
        function ChordParameterNameEditFieldValueChanged(app, event)
            app.updateEquationLabels();
        end

        % Value changed function: NacaQualitySpinner
        function NacaQualitySpinnerValueChanged(app, event)
            tic();
            app.naca.ComputeSurface(app.getQuality());
            app.NacaElapsedLabel.Text = sprintf("%.3fms",toc() * 1000);
            app.updateYf();
        end

        % Value changed function: LowerSurfaceQualitySpinner, 
        % ...and 1 other component
        function SurfaceQualitySpinnerValueChanged(app, event)
            app.updateYf();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.yFoil10UIFigure = uifigure('Visible', 'off');
            app.yFoil10UIFigure.Name = 'yFoil 1.0';
            app.yFoil10UIFigure.Icon = fullfile(pathToMLAPP, 'Logo.png');
            app.GridLayout5 = uigridlayout(app.yFoil10UIFigure);
            app.Axes = uiaxes(app.GridLayout5);
            title(app.Axes, 'Preview')
            xlabel(app.Axes, 'Chord')
            ylabel(app.Axes, 'Y')
            zlabel(app.Axes, 'Z')
            app.Axes.XAxisLocation = 'top';
            app.EquationsPanel = uipanel(app.GridLayout5);
            app.EquationsPanel.Title = 'Equations';
            app.GridLayout3 = uigridlayout(app.EquationsPanel);
            app.elux = uilabel(app.GridLayout3);
            app.elux.Tooltip = {'Upper surface x equation'};
            app.ecux = uibutton(app.GridLayout3, 'push');
            app.ecux.ButtonPushedFcn = createCallbackFcn(app, @copyButtonPushed, true);
            app.ecux.Tag = 'ux';
            app.ecux.Tooltip = {'Copy equation to clipboard'};
            app.ecux.Text = 'Copy ux';
            app.eluy = uilabel(app.GridLayout3);
            app.eluy.Tooltip = {'Upper surface y equation'};
            app.eluy.Text = 'Label2';
            app.ecuy = uibutton(app.GridLayout3, 'push');
            app.ecuy.ButtonPushedFcn = createCallbackFcn(app, @copyButtonPushed, true);
            app.ecuy.Tag = 'uy';
            app.ecuy.Tooltip = {'Copy equation to clipboard'};
            app.ecuy.Text = 'Copy uy';
            app.ellx = uilabel(app.GridLayout3);
            app.ellx.Tooltip = {'Lower surface x equation'};
            app.ellx.Text = 'Label3';
            app.eclx = uibutton(app.GridLayout3, 'push');
            app.eclx.ButtonPushedFcn = createCallbackFcn(app, @copyButtonPushed, true);
            app.eclx.Tag = 'lx';
            app.eclx.Tooltip = {'Copy equation to clipboard'};
            app.eclx.Text = 'Copy lx';
            app.elly = uilabel(app.GridLayout3);
            app.elly.Tooltip = {'Lower surface y equation'};
            app.elly.Text = 'Label4';
            app.ecly = uibutton(app.GridLayout3, 'push');
            app.ecly.ButtonPushedFcn = createCallbackFcn(app, @copyButtonPushed, true);
            app.ecly.Tag = 'ly';
            app.ecly.Tooltip = {'Copy equation to clipboard'};
            app.ecly.Text = 'Copy ly';
            app.ReferenceProfilePanel = uipanel(app.GridLayout5);
            app.ReferenceProfilePanel.Title = 'Reference Profile';
            app.GridLayout2 = uigridlayout(app.ReferenceProfilePanel);
            app.NACAEditField_2Label = uilabel(app.GridLayout2);
            app.NACAEditField_2Label.Text = 'NACA';
            app.QueryField = uieditfield(app.GridLayout2, 'text');
            app.QueryField.CharacterLimits = [4 4];
            app.QueryField.InputType = 'digits';
            app.QueryField.ValueChangedFcn = createCallbackFcn(app, @QueryFieldValueChanged, true);
            app.QueryField.ValueChangingFcn = createCallbackFcn(app, @QueryFieldValueChanging, true);
            app.QueryField.Tooltip = {'Thter the 4-digit NACA specifier.'; 'The first digit is the chamber (%).'; 'The second digit is chamber position (d).'; 'The third and fourth digits are thickness (%).'};
            app.QueryField.Placeholder = 'XXXX';
            app.QueryField.Value = '2412';
            app.Lamp = uilamp(app.GridLayout2);
            app.Lamp.Tooltip = {'Green indicates that pharsing was succesful'};
            app.QualitySpinnerLabel = uilabel(app.GridLayout2);
            app.QualitySpinnerLabel.Text = 'Quality';
            app.NacaQualitySpinner = uispinner(app.GridLayout2);
            app.NacaQualitySpinner.Limits = [1 6];
            app.NacaQualitySpinner.ValueChangedFcn = createCallbackFcn(app, @NacaQualitySpinnerValueChanged, true);
            app.NacaQualitySpinner.Tooltip = {'Higher quality increases the number of generated points of the naca profile. This improves regression.'};
            app.NacaQualitySpinner.Value = 4;
            app.NacaElapsedLabel = uilabel(app.GridLayout2);
            app.NacaElapsedLabel.Tooltip = {'Time elapsed computing the reference profile.'};
            app.NacaElapsedLabel.Text = '';
            app.Image = uiimage(app.GridLayout5);
            app.Image.URL = 'https://github.com/Examath';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'ExalabLogo.png');
            app.Hyperlink = uihyperlink(app.GridLayout5);
            app.Hyperlink.URL = 'https://github.com/Examath/yFoil/wiki';
            app.Hyperlink.Text = 'Help';
            app.YFoilProfileOptionsPanel_2 = uipanel(app.GridLayout5);
            app.YFoilProfileOptionsPanel_2.Title = 'YFoil Profile Options';
            app.GridLayout6 = uigridlayout(app.YFoilProfileOptionsPanel_2);
            app.UpperSurfaceQualitySpinnerLabel = uilabel(app.GridLayout6);
            app.UpperSurfaceQualitySpinnerLabel.Text = 'Upper Surface Quality';
            app.UpperSurfaceQualitySpinner = uispinner(app.GridLayout6);
            app.UpperSurfaceQualitySpinner.Step = 5e-05;
            app.UpperSurfaceQualitySpinner.Limits = [0.9 0.99999];
            app.UpperSurfaceQualitySpinner.ValueDisplayFormat = '%11.5g';
            app.UpperSurfaceQualitySpinner.ValueChangedFcn = createCallbackFcn(app, @SurfaceQualitySpinnerValueChanged, true);
            app.UpperSurfaceQualitySpinner.Tooltip = {'The minimum r² value for the yFoil approximation on the upper surface.'};
            app.UpperSurfaceQualitySpinner.Value = 0.9998;
            app.ChordParameterNameEditFieldLabel = uilabel(app.GridLayout6);
            app.ChordParameterNameEditFieldLabel.Text = 'Chord Parameter Name';
            app.ChordParameterNameEditField = uieditfield(app.GridLayout6, 'text');
            app.ChordParameterNameEditField.CharacterLimits = [1 99];
            app.ChordParameterNameEditField.ValueChangedFcn = createCallbackFcn(app, @ChordParameterNameEditFieldValueChanged, true);
            app.ChordParameterNameEditField.Tooltip = {'The string to use when generating equations refering to the parameter referring to the chord.'; 'You may also use a static value like ''350 ul'''};
            app.ChordParameterNameEditField.Placeholder = 'chord';
            app.ChordParameterNameEditField.Value = 'chord';
            app.LowerSurfaceQualitySpinnerLabel = uilabel(app.GridLayout6);
            app.LowerSurfaceQualitySpinnerLabel.Text = 'Lower Surface Quality';
            app.LowerSurfaceQualitySpinner = uispinner(app.GridLayout6);
            app.LowerSurfaceQualitySpinner.Step = 0.001;
            app.LowerSurfaceQualitySpinner.Limits = [0.9 0.99999];
            app.LowerSurfaceQualitySpinner.ValueDisplayFormat = '%11.5g';
            app.LowerSurfaceQualitySpinner.ValueChangedFcn = createCallbackFcn(app, @SurfaceQualitySpinnerValueChanged, true);
            app.LowerSurfaceQualitySpinner.Tooltip = {'The minimum r² value for the yFoil approximation on the lower surface.'};
            app.LowerSurfaceQualitySpinner.Value = 0.99;
            app.YFoilUpperOutputLabel = uilabel(app.GridLayout6);
            app.YFoilUpperOutputLabel.Tooltip = {'''n'' is the degree of the yFoil equation. ''r²'' is the actual r² value of the yFoil approximation with respect to the reference profile.'};
            app.YFoilUpperOutputLabel.Text = 'TBC';
            app.YFoilLowerOutputLabel = uilabel(app.GridLayout6);
            app.YFoilLowerOutputLabel.Tooltip = {'''n'' is the degree of the yFoil equation. ''r²'' is the actual r² value of the yFoil approximation with respect to the reference profile.'};
            app.YFoilLowerOutputLabel.Text = 'TBC';
            app.YFoilElapsedLabel = uilabel(app.GridLayout6);
            app.YFoilElapsedLabel.Tooltip = {'Time elapsed computing the yFoil equations'};
            app.YFoilElapsedLabel.Text = 'TBC ms';
            % Show the figure after all components are created
            app.yFoil10UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = yFoil

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.yFoil10UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.yFoil10UIFigure)
        end
    end
end